classdef Two_D_Heat_Eqn_Solver_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HeatFlowAnimationTab          matlab.ui.container.Tab
        SpeedLabel                    matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
        AnimationSlider               matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        PlayButton                    matlab.ui.control.StateButton
        SimulationAxes                matlab.ui.control.UIAxes
        ContourTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        DsurfaceplotTab               matlab.ui.container.Tab
        VerticalTiltLabel             matlab.ui.control.Label
        HorizontalRotationLabel       matlab.ui.control.Label
        RestoreDefaultButton          matlab.ui.control.Button
        AzSlider                      matlab.ui.control.Slider
        AzSliderLabel                 matlab.ui.control.Label
        ElSlider                      matlab.ui.control.Slider
        ElSliderLabel                 matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        RunSimulationButton           matlab.ui.control.Button
        DHeatEquationSolverLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        EditField_15                  matlab.ui.control.EditField
        BottomLeftCornerLabel         matlab.ui.control.Label
        EditField_14                  matlab.ui.control.EditField
        BottomRightCornerLabel        matlab.ui.control.Label
        EditField_13                  matlab.ui.control.EditField
        TopLeftCornerLabel            matlab.ui.control.Label
        EditField_12                  matlab.ui.control.EditField
        TopRightCornerLabel           matlab.ui.control.Label
        BothinitialandboudaryconditioncanbefunctionsabsfloorceliLabel  matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        DropDown                      matlab.ui.control.DropDown
        EditField_11                  matlab.ui.control.EditField
        WidthLabel                    matlab.ui.control.Label
        EditField_10                  matlab.ui.control.EditField
        LengthLabel                   matlab.ui.control.Label
        EditField_9                   matlab.ui.control.EditField
        DiffusivityEditField          matlab.ui.control.EditField
        DiffusivityEditFieldLabel     matlab.ui.control.Label
        EditField_8                   matlab.ui.control.EditField
        BottomBoundaryConditionLabel  matlab.ui.control.Label
        EditField_7                   matlab.ui.control.EditField
        TopBoundaryConditionLabel     matlab.ui.control.Label
        EditField_6                   matlab.ui.control.EditField
        RightBoundaryConditionLabel   matlab.ui.control.Label
        EditField_5                   matlab.ui.control.EditField
        LeftBoundaryConditionLabel    matlab.ui.control.Label
        EditField_4                   matlab.ui.control.EditField
        FinalTimeTLabel               matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        TimesteptLabel                matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SpacestepyLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SpacestepxLabel               matlab.ui.control.Label
        BoundaryConditionLabel        matlab.ui.control.Label
        InitialConditionLabel         matlab.ui.control.Label
        DiscretizedDomainLabel        matlab.ui.control.Label
        ObjectDetailsLabel            matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        
        t_final % Description
        delta_x % Description
        delta_y % Description
        delta_t % Description
        left_bc % Description
        right_bc % Description
        top_bc % Description
        bottom_bc % Description
        initial_condition % Description
        simulation_data % Description
        t_values % Description
        image_handle % Description
        L % Description
        W % Description
        alpha % Description
        x_values % Description
        y_values % Description
        top_right % Description
        top_left % Description
        bottom_right % Description
        bottom_left % Description
        speed % Description
        safe_limit % Description
        last_state % Description
        last_length % Description
        last_width % Description
        last_delta_x % Description
        last_delta_y % Description
    end
    
    methods (Access = private)

    function runHeatSimulation(app)
        
        % 1. Define Equation Strings
        eqInputs = {
            app.initial_condition, 'Initial Condition';
            app.left_bc,           'Left Boundary';
            app.right_bc,          'Right Boundary';
            app.top_bc,            'Top Boundary';
            app.bottom_bc,         'Bottom Boundary'
        };
        
        % 2. Parameters that MUST be positive (Physical Dimensions/Steps)
        strictPosInputs = {
            app.L,       'Length';
            app.W,       'Width';
            app.t_final, 'Final Time';
            app.alpha,   'Diffusivity (alpha)';
            app.delta_x, 'Space step x';
            app.delta_y, 'Space step y';
            app.delta_t, 'Time step'
        };
        
        % 3. Parameters that can be ANY real number (Temperatures/Constants)
        anyValueInputs = {
            app.top_right,    'Top Right Corner';
            app.top_left,     'Top Left Corner';
            app.bottom_right, 'Bottom Right Corner';
            app.bottom_left,  'Bottom left Corner'
        };
        
        % --- Validate Equation Strings ---
        for i = 1:size(eqInputs, 1)
            val = eqInputs{i, 1};
            if isempty(val) || strlength(strtrim(string(val))) == 0
                uialert(app.UIFigure, sprintf('The field "%s" is empty!', eqInputs{i, 2}), 'Input Error');
                return;
            end
        end
        
        % --- Validate All Numeric Values (Common Checks) ---
        allNumInputs = [strictPosInputs; anyValueInputs];
        
        for i = 1:size(allNumInputs, 1)
            val = allNumInputs{i, 1};
            name = allNumInputs{i, 2};
            
            % Check for Empty, Scalar, or NaN (Conversion failures)
            if isempty(val) || ~isscalar(val) || isnan(val)
                uialert(app.UIFigure, sprintf('The field "%s" contains an invalid number or is empty.', name),...
                    'Input Error');
                return;
            end
        end
        
        % --- Specific Check: Physical Positivity ---
        for i = 1:size(strictPosInputs, 1)
            if strictPosInputs{i, 1} <= 0
                uialert(app.UIFigure, sprintf('"%s" must be greater than zero.', strictPosInputs{i, 2}),...
                    'Input Error');
                return;
            end
        end

        if (app.L/app.delta_x) <= 1
             uialert(app.UIFigure,'Too large x-step compared to length', 'Error');
            return
        end
        if (app.W/app.delta_y) <= 1
             uialert(app.UIFigure,'Too large y-step compared to width', 'Error');
            return
        end
        if (app.t_final/app.delta_t) <= 1
            uialert(app.UIFigure,'Too large time step compared to final time', 'Error');
            return
        end

        %1. Stability Check
        fourier_number = (app.alpha*app.delta_t)*((1/app.delta_x^2)+(1/app.delta_y^2));

        if fourier_number > 0.5
            % Constant for the stability limit
            limit_const = 0.5; 
            
            % 1. Max Diffusivity (alpha)
            alpha_max = limit_const / (app.delta_t * (1/app.delta_x^2 + 1/app.delta_y^2));
            
            % 2. Max Time Step (delta_t)
            dt_max = limit_const / (app.alpha * (1/app.delta_x^2 + 1/app.delta_y^2));
            
           % Calculate the term inside the square root
            term_x = (0.5 / (app.alpha * app.delta_t)) - (1 / app.delta_y^2);
            term_y = (0.5 / (app.alpha * app.delta_t)) - (1 / app.delta_x^2);
            
            % Logic to handle "Impossible" spatial steps
            if term_x > 0
                dx_min = sqrt(1 / term_x);
                dx_str = sprintf('%.2e', dx_min);
            else
                dx_str = 'No possible limit with current time step, diffusivity and y-step';
            end
            
            if term_y > 0
                dy_min = sqrt(1 / term_y);
                dy_str = sprintf('%.2e', dy_min);
            else
                dy_str = 'No possible limit with current time step, diffusivity and x-step';
            end
            
            % Construct message with scientific notation
            message = sprintf(['Please set\n',...
                '1. Alpha (α) < %.2e or\n',...
                '2. Time Step (Δt) < %.2e or\n',...
                '3. X-Step (Δx) > %s or\n',...
                '4. Y-Step (Δy) > %s\n' ...
                'leaving other parameters unchanged\n' ...
                '------------------------------------------\n', ...
                'Current Stability Factor: %.3f (Limit: 0.5)'],...
                alpha_max, dt_max, dx_str, dy_str, fourier_number);
            
            uialert(app.UIFigure, message, 'Stability Warning');
            return
        end
        % 2. Calculate Grids
        
        col_num = ceil(app.L/app.delta_x) + 1; 
        new_dx = app.L/(col_num - 1);
        app.x_values = 0:new_dx:app.L;
        
        row_num = ceil(app.W/app.delta_y) + 1;
        new_dy = app.W/(row_num - 1);
        app.y_values = 0:new_dy:app.W;
        
        time_pts = ceil(app.t_final/app.delta_t) + 1;
        new_dt = app.t_final/(time_pts - 1);
        app.t_values = 0:new_dt:app.t_final;
        
        %3. Check If RAM supports storage of simulation data
        if checkMemorySafe(app,[row_num,col_num,time_pts]) == false
            return
        end
        % 3. Pre-allocate 
        app.simulation_data = zeros(row_num, col_num, time_pts);
        % 4. Initial Condition Logic
        app.safe_limit = 1e10; % Adjust this based on your expected physics
        if strcmp(app.initial_condition, 'Gaussian')
            % 1. Create the 2D coordinate matrices
            [X, Y] = meshgrid(app.x_values, app.y_values);
            
            % 2. Parameters
            centerX = app.L/2;
            centerY = app.W/2;
            sigma = 3.0;
            
            % 3. Calculate Gaussian distribution (Vectorized)
            % Use .^ for element-wise squaring
            exponent = -((X - centerX).^2 + (Y - centerY).^2) / (2 * sigma^2);
            app.simulation_data(:,:,1) = 100*exp(exponent);
        elseif strcmp(app.initial_condition, 'Center-Block')
            % 1. Initialize the whole first slice to 10 degrees
            app.simulation_data(:,:,1) = 10; 
            
            % 2. Define the middle range (e.g., from 40% to 60% of the grid)
            row_start = floor(row_num * 0.3);
            row_end   = ceil(row_num * 0.7);
            col_start = floor(col_num * 0.3);
            col_end   = ceil(col_num * 0.7);
            
            % 3. Apply the heat to that specific block
            app.simulation_data(row_start:row_end, col_start:col_end, 1) = 500;
        elseif strcmp(app.initial_condition, 'Final state of last simulation')
            app.simulation_data(:,:,1) = app.last_state;
        else
            clean_str = vectorize(lower(replace(char(app.initial_condition), 'e^', 'exp')));
            func_str = ['@(x,y) (', clean_str, ')'];
            [X, Y] = meshgrid(app.x_values, app.y_values);
            try
                f_init = str2func(func_str);
                init_mat = f_init(X, Y);
                if isscalar(init_mat), init_mat = init_mat * ones(size(X)); end
                app.simulation_data(:,:,1) = init_mat;
            catch ME
                uialert(app.UIFigure, ME.message, 'Initial Condition Error');
                return;
            end
            
            
            if any(abs(app.simulation_data(2:end-1, 2:end-1, 1)) > app.safe_limit, 'all')
                uialert(app.UIFigure, ['Initial condition is very high at some point(>', num2str(app.safe_limit),')'], 'Error');
                return;
            elseif any(imag(app.simulation_data(2:end-1,2:end-1,1)) ~= 0, 'all')
                uialert(app.UIFigure,'Initial condition is imaginary at some point.','Error');
                return;
            end
        end
    
        % 4. Apply Boundary Conditions (Functional or Constant Strings)
        try
            % --- Top Boundary (Varies with x) ---
            clean_top = vectorize(lower(replace(app.top_bc, 'e^', 'exp')));
            f_top = str2func(['@(x) ', clean_top]);
            v_top = f_top(app.x_values); 
            if isscalar(v_top), v_top = v_top * ones(size(app.x_values)); end
            if any(abs(v_top) > app.safe_limit, 'all')
                uialert(app.UIFigure, ['Top boundary condition is very high at some point(>', num2str(app.safe_limit),')'], 'Error');
                return;
            elseif any(imag(v_top) ~= 0, 'all')
                uialert(app.UIFigure,'Top boundary condition is imaginary at some point.','Error');
                return;
            end
            % Expand across the time dimension (3rd dimension)
            app.simulation_data(end, :, :) = repmat(v_top, [1, 1, time_pts]);

            % --- Bottom Boundary (Varies with x) ---
            clean_bot = vectorize(lower(replace(app.bottom_bc, 'e^', 'exp')));
            f_bot = str2func(['@(x) ', clean_bot]);
            v_bot = f_bot(app.x_values);
            if isscalar(v_bot), v_bot = v_bot * ones(size(app.x_values)); end
            if any(abs(v_bot) > app.safe_limit, 'all')
                uialert(app.UIFigure, ['Bottom boundary condition is very high at some point(>', num2str(app.safe_limit),')'], 'Error');
                return;
            elseif any(imag(v_bot) ~= 0, 'all')
                uialert(app.UIFigure,'Bottom boundary condition is imaginary at some point.','Error');
                return;
            end 
            app.simulation_data(1, :, :) = repmat(v_bot, [1, 1, time_pts]);

            % --- Left Boundary (Varies with y) ---
            clean_left = vectorize(lower(replace(app.left_bc, 'e^', 'exp')));
            f_left = str2func(['@(y) ', clean_left]);
            v_left = f_left(app.y_values);
            if isscalar(v_left), v_left = v_left * ones(size(app.y_values)); end
            if any(abs(v_left) > app.safe_limit, 'all')
                uialert(app.UIFigure, ['Left boundary condition is very high at some point(>', num2str(app.safe_limit),')'], 'Error');
                return;
            elseif any(imag(v_left) ~= 0, 'all')
                uialert(app.UIFigure,'Left boundary condition is imaginary at some point.','Error');
                return;
            end 
            % Reshape to [row_num x 1] before repmat to match the vertical edge
            app.simulation_data(:, 1, :) = repmat(v_left(:), [1, 1, time_pts]);

            % --- Right Boundary (Varies with y) ---
            clean_right = vectorize(lower(replace(app.right_bc, 'e^', 'exp')));
            f_right = str2func(['@(y) ', clean_right]);
            v_right = f_right(app.y_values);
            if isscalar(v_right), v_right = v_right * ones(size(app.y_values)); end
            if any(abs(v_right) > app.safe_limit, 'all')
                uialert(app.UIFigure, ['Right boundary condition is very high at some point(>', num2str(app.safe_limit),')'], 'Error');
                return;
            elseif any(imag(v_right) ~= 0, 'all')
                uialert(app.UIFigure,'Right boundary condition is imaginary at some point.','Error');
                return;
            end
            app.simulation_data(:, end, :) = repmat(v_right(:), [1, 1, time_pts]);
            
        catch ME
            uialert(app.UIFigure, ME.message, 'Boundary Condition Error');
            return;
        end
        
        
        % Top-Left: Last Row, First Column
        app.simulation_data(end, 1, :) = app.top_left;
        
        % Top-Right: Last Row, Last Column
        app.simulation_data(end, end, :) = app.top_right;
        
        % Bottom-Left: First Row, First Column
        app.simulation_data(1, 1, :) = app.bottom_left;
        
        % Bottom-Right: First Row, Last Column
        app.simulation_data(1, end, :) = app.bottom_right;
        % 5. Main Solver Loop
        % Initialize the Dialog
        d = uiprogressdlg(app.UIFigure, 'Title', 'Solving Heat Equation', ...
            'Message', 'Starting time integration...', 'Cancelable', 'on');
        is_simulation_canceled = 0;
        for t = 2:time_pts
            % 2. Check for user cancellation
            if d.CancelRequested
                is_simulation_canceled = 1;
                break
            end
        
            % 3. Main Solver Logic
            app.simulation_data(2:end-1, 2:end-1, t) = ...
                app.simulation_data(2:end-1, 2:end-1, t-1) + ...
                (app.alpha * new_dt / new_dx^2) * ...
                (app.simulation_data(2:end-1, 3:end, t-1) - 2*app.simulation_data(2:end-1, 2:end-1, t-1) + app.simulation_data(2:end-1, 1:end-2, t-1)) + ...
                (app.alpha * new_dt / new_dy^2) * ...
                (app.simulation_data(3:end, 2:end-1, t-1) - 2*app.simulation_data(2:end-1, 2:end-1, t-1) + app.simulation_data(1:end-2, 2:end-1, t-1));
            
            % 4. Update progress every 10 frames to maintain speed
            if mod(t, 10) == 0
                d.Value = t/time_pts;
                d.Message = sprintf('Simulating: Time step %d of %d', t, time_pts);
            end
        end
        if ~ismember(app.DropDown_2.Items, 'Final state of last simulation')
            app.DropDown_2.Items = [app.DropDown_2.Items, 'Final state of last simulation'];
        end
        
        app.last_state = app.simulation_data(:,:,end);
        app.last_delta_x = app.delta_x;
        app.last_delta_y = app.delta_y;
        app.last_length = app.L;
        app.last_width = app.W;
        % 5. Clean up
        close(d);
        if is_simulation_canceled == 1
            return;
        end
        % 6. Plot and Animate
        % Setup UI for Animation
        app.SimulationAxes.Visible = 'on';
        app.AnimationSlider.Visible = 'on';
        app.PlayButton.Visible = 'on';
        app.ResetButton.Visible = 'on';
        app.SpeedSlider.Visible = 'on';
        app.SpeedLabel.Visible = "on";
       
        % Set Slider Limits to actual time (0 to 3)
        app.AnimationSlider.Limits = [app.t_values(1), app.t_values(end)];
        app.AnimationSlider.Value = app.t_values(1);

        app.PlayButton.Value = 0;
        app.PlayButton.Text = "Play";
        
        % Create the initial plot handle
        app.image_handle = imagesc(app.SimulationAxes, app.x_values, app.y_values, app.simulation_data(:,:,1));
        set(app.SimulationAxes, 'YDir', 'normal');
        colorbar(app.SimulationAxes);
        axis(app.SimulationAxes, 'equal', 'tight');
        clim(app.SimulationAxes, [min(app.simulation_data(:)), max(app.simulation_data(:))]);
        
        app.updateFrame(0); % Show first frame

        Z = app.simulation_data(:,:,end);  % Get the data at the last time step (final frame)
        [X, Y] = meshgrid(app.x_values, app.y_values);
        % Create a contour plot
        contourf(app.UIAxes,X,Y,Z,25);  % 20 contour levels
        colorbar(app.UIAxes);  % Add a colorbar
        title(app.UIAxes, 'Contour Plot at Final Time');
        
        % Show the Contour UI Axes
        app.UIAxes.Visible = 'on';  % Make the UIAxes in the Contour tab visible
        
        surf(app.UIAxes2,X,Y,Z,'EdgeColor', 'none');  % Plot the surface
        colorbar(app.UIAxes2);
        % camlight(app.UIAxes2,'headline');
        % lighting(app.UIAxes2,'gouraud');
        title( app.UIAxes2, 'Surface Plot at Final Time');
        xlabel(app.UIAxes2, 'X');
        ylabel(app.UIAxes2, 'Y');
        zlabel(app.UIAxes2, 'Z');
        
        % Ensure the axes are in 3D
        axis(app.UIAxes2, 'tight');
        view(app.UIAxes2, 3);  % Set the view to 3D
        
        % Show the Surface Plot UI Axes
        app.UIAxes2.Visible = 'on';  % Make the UIAxes in the Surface tab visible
        app.AzSlider.Visible = 'on';
        app.ElSlider.Visible = 'on';
        app.RestoreDefaultButton.Visible = 'on';
        app.HorizontalRotationLabel.Visible = 'on';
        app.VerticalTiltLabel.Visible = 'on';
    end

    function updateFrame(app, val)
        % val is now a time value (e.g., 1.57)
        times = app.t_values;
        data = app.simulation_data;
        h = app.image_handle;
        ax = app.SimulationAxes;
        
        % 1. Find the index where t_values is closest to the slider value
        [~, idx] = min(abs(times - val));
        
        % 2. Safety bounds (though min/abs usually handles this)
        if idx < 1, idx = 1; end
        if idx > size(data, 3), idx = size(data, 3); end
        
        % 3. Update the plot and title
        set(h, 'CData', data(:,:,idx));
        title(ax, ['Time: ', num2str(times(idx), '%.3f'), 's']);
    end
        
    function safe = checkMemorySafe(app, dims)
        % 1. Check Software Dimension Limit
        % MATLAB's internal 'computer' function provides this limit
        [~, maxDimSize] = computer; 
        if any(dims > maxDimSize)
            uialert(app.UIFigure, ...
                sprintf('One of your dimensions exceeds the MATLAB internal limit of %d.', maxDimSize), ...
                'Dimension Error', 'Icon', 'error');
            safe = false;
            return;
        end
    
        % 2. Calculate requested size in GB (8 bytes for double)
        requestedBytes = prod(dims) * 8;
        requestedGB = requestedBytes / (1024^3);
        
        % 3. Check Windows Physical Memory
        m = memory;

        if isfield(m, 'MaxPossibleArrayBytes')
            maxPossibleBytes = m.MaxPossibleArrayBytes;
        elseif isfield(m, 'MemAvailableAllArrays')
            % Fallback for certain older versions or specific configurations
            maxPossibleBytes = m.MemAvailableAllArrays;
        else
            % Final fallback: use a conservative estimate of System Memory
            maxPossibleBytes = m.MemPhysicalMemory.Available;
        end
        
        % 4. Set Safety Threshold (e.g., 80% of available contiguous block)
        safeLimitBytes = maxPossibleBytes * 0.8;
        
        if requestedBytes > safeLimitBytes
            msg = {sprintf('The requested array (%.2f GB) is too large for your RAM.', requestedGB), ...
                   sprintf('Current safe limit is %.2f GB.', safeLimitBytes / (1024^3)), ...
                   '', ...
                   'Please increase steps to avoid a system hang.'};
               
            uialert(app.UIFigure, msg, 'Memory Safety Limit', ...
                'Icon', 'warning', 'Modal', true);
            safe = false;
        else
            safe = true;
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
        % Run the math
        runHeatSimulation(app);
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            app.initial_condition = value;       
        end

        % Value changed function: DiffusivityEditField
        function DiffusivityEditFieldValueChanged(app, event)
        app.alpha  = str2double(app.DiffusivityEditField.Value);
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
          value=app.EditField_5.Value ;
          app.left_bc= value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.delta_x= str2double(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.delta_y=str2double(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.delta_t=str2double(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.t_final=str2double(value);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.right_bc= value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.top_bc= value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.bottom_bc= value;
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.L=str2double(value);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.W=str2double(value);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            
            
            % 1. Setup local variables for readability
            btn = app.PlayButton;
            sld = app.AnimationSlider;
            times = app.t_values;
            
            % 2. Update the button text based on its state
            if btn.Value == 1
                btn.Text = 'Pause';
            else
                btn.Text = 'Play';
                return; % Exit the function if the user just clicked "Pause"
            end
            app.speed = 200;
            while btn.Value == 1 && sld.Value < sld.Limits(2)
            
            % Calculate a time increment (e.g., 1% of total time per step)
            % Or use a fixed value like 0.05
            
            dt_step = (sld.Limits(2) - sld.Limits(1)) / app.speed; 

            if dt_step > app.delta_t
                 % Advance the slider by TIME, not index
                newValue = sld.Value + dt_step;
            else
                newValue = sld.Value + app.delta_t;
            end
            
            % % Advance the slider by TIME, not index
            % newValue = sld.Value + dt_step;
            
            % Ensure we don't exceed the max time
            if newValue >= sld.Limits(2)
                sld.Value = sld.Limits(2);
            else
                sld.Value = newValue;
            end
            
            % Update the visual plot
            % updateFrame handles finding the correct matrix index for us!
            app.updateFrame(sld.Value);
            
            % Force UI update and control playback speed
            drawnow;
            pause(0.01); 
            
            % Auto-reset button if we reach the end
            
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        % 1. Set slider to the very first time point (usually 0)
        app.AnimationSlider.Value = app.t_values(1);
        
        % 2. Update the plot to the first frame using that time value
        app.updateFrame(app.t_values(1));
        
        % 3. Ensure the Play button is reset to 'Play' state
        app.PlayButton.Value = 0;
        app.PlayButton.Text = 'Play';
        end

        % Value changing function: AnimationSlider
        function AnimationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.updateFrame(changingValue);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

           value = app.DropDown.Value;
        
           switch value
                case 'Copper'
                    app.alpha = 1.11e-4;
                    app.DiffusivityEditField.Editable = false;
                    app.DiffusivityEditField.Value = num2str(app.alpha); 
                case 'Aluminium'
                    app.alpha  = 97.5e-6;
                    app.DiffusivityEditField.Editable = false;
                    app.DiffusivityEditField.Value = num2str(app.alpha);
                case 'Silver'
                    app.alpha  = 1.65e-4;
                    app.DiffusivityEditField.Editable = false;
                    app.DiffusivityEditField.Value = num2str(app.alpha);
                case 'Tin'
                    app.alpha  = 4e-5;
                    app.DiffusivityEditField.Editable = false;
                    app.DiffusivityEditField.Value = num2str(app.alpha);
               otherwise
                    app.DiffusivityEditField.Editable = true;
                    app.alpha = [];  
                    app.DiffusivityEditField.Value = ''; 
                    input_value = str2double(app.DiffusivityEditField.Value);
                    app.alpha = input_value;
           end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)

             value = app.DropDown_2.Value;

           switch value
                case 'Gaussian'
                    app.initial_condition = "Gaussian";
                    app.EditField_9.Editable = false;
                    app.EditField_9.Value = app.initial_condition; 
                    app.EditField_10.Editable = true;
                    app.EditField_11.Editable = true;
                    app.EditField.Editable = true;
                    app.EditField_2.Editable = true;
               case 'Center Block'
                    app.initial_condition  = "Center-Block";
                    app.EditField_9.Editable = false;
                    app.EditField_9.Value = "Center Block";
                    app.EditField_10.Editable = true;
                    app.EditField_11.Editable = true;
                    app.EditField.Editable = true;
                    app.EditField_2.Editable = true;
               case 'Final state of last simulation'
                    app.initial_condition  = "Final state of last simulation";
                    app.EditField_9.Editable = false;
                    app.EditField_9.Value = "Final state of last simulation";
                    app.EditField_10.Value = num2str(app.last_length);
                    app.EditField_10.Editable = false;
                    app.L = app.last_length;
                    app.EditField_11.Value = num2str(app.last_width);
                    app.EditField_11.Editable = false;
                    app.W = app.last_width;
                    app.EditField.Value = num2str(app.last_delta_x);
                    app.EditField.Editable = false;
                    app.delta_x = app.last_delta_x;
                    app.EditField_2.Value = num2str(app.last_delta_y);
                    app.EditField_2.Editable = false;
                    app.delta_y = app.last_delta_y;
               otherwise
                    app.EditField_9.Editable = true;
                    app.initial_condition = [];  
                    app.EditField_9.Value = '';
                    app.initial_condition = app.EditField_9.Value;
                    app.EditField_10.Editable = true; 
                    app.EditField_11.Editable = true;
                    app.EditField.Editable = true;
                    app.EditField_2.Editable = true;
                   
            end
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            app.top_right = str2double(value);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            app.top_left = str2double(value);
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            app.bottom_right = str2double(value);
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            app.bottom_left = str2double(value);
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            app.speed = (6-value)*100;
        end

        % Value changing function: ElSlider
        function ElSliderValueChanging(app, event)
            % Get the current position of the other slider
            currentAz = app.AzSlider.Value;
            % Get the moving value from the event
            changingEl = event.Value;
            
            % Apply to your 3D axes
            view(app.UIAxes2, [currentAz, changingEl]);
            
            % Smooth rendering
            drawnow limitrate;
        end

        % Value changing function: AzSlider
        function AzSliderValueChanging(app, event)
            % Get the moving value from the event
            changingAz = event.Value;
            % Get the current position of the other slider
            currentEl = app.ElSlider.Value;
            
            % Apply to your 3D axes
            view(app.UIAxes2, [changingAz, currentEl]);
            
            % Smooth rendering
            drawnow limitrate;
                    
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            % Default MATLAB 3D view is [-37.5, 30]
            app.AzSlider.Value = -37.5;
            app.ElSlider.Value = 30;
            view(app.UIAxes2, [-37.5, 30]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ObjectDetailsLabel = uilabel(app.Panel);
            app.ObjectDetailsLabel.Text = 'Object Details';
            app.DiscretizedDomainLabel = uilabel(app.Panel);
            app.DiscretizedDomainLabel.Text = 'Discretized Domain';
            app.InitialConditionLabel = uilabel(app.Panel);
            app.InitialConditionLabel.Text = 'Initial Condition';
            app.BoundaryConditionLabel = uilabel(app.Panel);
            app.BoundaryConditionLabel.Text = 'Boundary Condition';
            app.SpacestepxLabel = uilabel(app.Panel);
            app.SpacestepxLabel.Text = 'Space step, x :';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SpacestepyLabel = uilabel(app.Panel);
            app.SpacestepyLabel.Text = 'Space step, y :';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.TimesteptLabel = uilabel(app.Panel);
            app.TimesteptLabel.Text = 'Time step, t :';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.FinalTimeTLabel = uilabel(app.Panel);
            app.FinalTimeTLabel.Text = 'Final Time, T :';
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.LeftBoundaryConditionLabel = uilabel(app.Panel);
            app.LeftBoundaryConditionLabel.Text = 'Left Boundary Condition :';
            app.EditField_5 = uieditfield(app.Panel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.RightBoundaryConditionLabel = uilabel(app.Panel);
            app.RightBoundaryConditionLabel.Text = 'Right Boundary Condition :';
            app.EditField_6 = uieditfield(app.Panel, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.TopBoundaryConditionLabel = uilabel(app.Panel);
            app.TopBoundaryConditionLabel.Text = 'Top Boundary Condition :';
            app.EditField_7 = uieditfield(app.Panel, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.BottomBoundaryConditionLabel = uilabel(app.Panel);
            app.BottomBoundaryConditionLabel.Text = 'Bottom Boundary Condition :';
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.DiffusivityEditFieldLabel = uilabel(app.Panel);
            app.DiffusivityEditFieldLabel.Text = 'Diffusivity : ';
            app.DiffusivityEditField = uieditfield(app.Panel, 'text');
            app.DiffusivityEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusivityEditFieldValueChanged, true);
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Placeholder = 'Ex: sin(a*x+b*y)';
            app.LengthLabel = uilabel(app.Panel);
            app.LengthLabel.Text = 'Length :';
            app.EditField_10 = uieditfield(app.Panel, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.WidthLabel = uilabel(app.Panel);
            app.WidthLabel.Text = 'Width :';
            app.EditField_11 = uieditfield(app.Panel, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Custom', 'Copper', 'Aluminium', 'Silver', 'Tin'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Custom';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'Custom', 'Gaussian', 'Center Block'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Custom';
            app.BothinitialandboudaryconditioncanbefunctionsabsfloorceliLabel = uilabel(app.Panel);
            app.BothinitialandboudaryconditioncanbefunctionsabsfloorceliLabel.Text = 'Both initial and boudary conditions (except corners) can be functions. abs(), floor(), ceil() may be used. ';
            app.TopRightCornerLabel = uilabel(app.Panel);
            app.TopRightCornerLabel.Text = 'Top Right Corner';
            app.EditField_12 = uieditfield(app.Panel, 'text');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.TopLeftCornerLabel = uilabel(app.Panel);
            app.TopLeftCornerLabel.Text = 'Top Left Corner';
            app.EditField_13 = uieditfield(app.Panel, 'text');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.BottomRightCornerLabel = uilabel(app.Panel);
            app.BottomRightCornerLabel.Text = 'Bottom Right Corner';
            app.EditField_14 = uieditfield(app.Panel, 'text');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.BottomLeftCornerLabel = uilabel(app.Panel);
            app.BottomLeftCornerLabel.Text = 'Bottom Left Corner';
            app.EditField_15 = uieditfield(app.Panel, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.DHeatEquationSolverLabel = uilabel(app.UIFigure);
            app.DHeatEquationSolverLabel.Text = '2D Heat Equation Solver';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HeatFlowAnimationTab = uitab(app.TabGroup);
            app.HeatFlowAnimationTab.Title = 'Heat Flow Animation';
            app.SimulationAxes = uiaxes(app.HeatFlowAnimationTab);
            title(app.SimulationAxes, 'Title')
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Visible = 'off';
            app.PlayButton = uibutton(app.HeatFlowAnimationTab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.HeatFlowAnimationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.SliderLabel = uilabel(app.HeatFlowAnimationTab);
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.AnimationSlider = uislider(app.HeatFlowAnimationTab);
            app.AnimationSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationSliderValueChanging, true);
            app.AnimationSlider.Visible = 'off';
            app.SpeedSliderLabel = uilabel(app.HeatFlowAnimationTab);
            app.SpeedSliderLabel.Visible = 'off';
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.HeatFlowAnimationTab);
            app.SpeedSlider.Limits = [1 5];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Visible = 'off';
            app.SpeedSlider.Value = 4;
            app.SpeedLabel = uilabel(app.HeatFlowAnimationTab);
            app.SpeedLabel.Visible = 'off';
            app.SpeedLabel.Text = 'Speed';
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.UIAxes = uiaxes(app.ContourTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.DsurfaceplotTab = uitab(app.TabGroup);
            app.DsurfaceplotTab.Title = '3D surface plot';
            app.UIAxes2 = uiaxes(app.DsurfaceplotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.ElSliderLabel = uilabel(app.DsurfaceplotTab);
            app.ElSliderLabel.Visible = 'off';
            app.ElSliderLabel.Text = 'ElSlider';
            app.ElSlider = uislider(app.DsurfaceplotTab);
            app.ElSlider.Limits = [-90 90];
            app.ElSlider.ValueChangingFcn = createCallbackFcn(app, @ElSliderValueChanging, true);
            app.ElSlider.Visible = 'off';
            app.ElSlider.Value = 30;
            app.AzSliderLabel = uilabel(app.DsurfaceplotTab);
            app.AzSliderLabel.Visible = 'off';
            app.AzSliderLabel.Text = 'AzSlider';
            app.AzSlider = uislider(app.DsurfaceplotTab);
            app.AzSlider.Limits = [-180 180];
            app.AzSlider.ValueChangingFcn = createCallbackFcn(app, @AzSliderValueChanging, true);
            app.AzSlider.Visible = 'off';
            app.AzSlider.Value = -37.5;
            app.RestoreDefaultButton = uibutton(app.DsurfaceplotTab, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Visible = 'off';
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.HorizontalRotationLabel = uilabel(app.DsurfaceplotTab);
            app.HorizontalRotationLabel.Visible = 'off';
            app.HorizontalRotationLabel.Text = 'Horizontal Rotation';
            app.VerticalTiltLabel = uilabel(app.DsurfaceplotTab);
            app.VerticalTiltLabel.Visible = 'off';
            app.VerticalTiltLabel.Text = 'Vertical Tilt';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpacestepxLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Two_D_Heat_Eqn_Solver_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end