classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RunSimulationButton           matlab.ui.control.Button
        DHeatEquationSolverLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        EditField_11                  matlab.ui.control.EditField
        WidthLabel                    matlab.ui.control.Label
        EditField_10                  matlab.ui.control.EditField
        LengthLabel                   matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        EditField_9                   matlab.ui.control.EditField
        DropDown                      matlab.ui.control.DropDown
        MaterialEditField             matlab.ui.control.EditField
        MaterialEditFieldLabel        matlab.ui.control.Label
        EditField_8                   matlab.ui.control.EditField
        BottomBoundaryConditionLabel  matlab.ui.control.Label
        EditField_7                   matlab.ui.control.EditField
        TopBoundaryConditionLabel     matlab.ui.control.Label
        EditField_6                   matlab.ui.control.EditField
        RightBoundaryConditionLabel   matlab.ui.control.Label
        EditField_5                   matlab.ui.control.EditField
        LeftBoundaryConditionLabel    matlab.ui.control.Label
        EditField_4                   matlab.ui.control.EditField
        FinalTimeTLabel               matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        TimesteptLabel                matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SpacestepyLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SpacestepxLabel               matlab.ui.control.Label
        BoundaryConditionLabel        matlab.ui.control.Label
        InitialConditionLabel         matlab.ui.control.Label
        DiscretizedDomainLabel        matlab.ui.control.Label
        ObjectDetailsLabel            matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        alpha % thermal diffusivity

        L  %Object details
        W 

        t_final  %domain
        delta_x 
        delta_y 
        delta_t 

        left_bc    %boundary condition
        right_bc
        top_bc
        bottom_bc


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
 
data = [app.L, app.W, app.t_final, app.delta_x, app.delta_y, app.delta_t, app.left_bc, app.right_bc, app.top_bc, app.bottom_bc,app.alpha];

writematrix(data, "GUI_values.xlsx");  % Saves in the current folder

evalin('base', 'Project_changed');


        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
           % app.EditField_9.Placeholder='Ex: sin(x+y)';
        end

        % Callback function
        function LengthEditFieldValueChanged(app, event)
            value = app.LengthEditField.Value;
            app.L=value;
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
           switch value
                case 'Copper'
                    app.alpha = 113e-6;
                    app.MaterialEditField.Value = num2str(app.alpha); 
                case 'Aluminium'
                    app.alpha  = 97.5e-6;
                    app.MaterialEditField.Value = num2str(app.alpha);
                case 'Iron'
                    app.alpha  = 22.8e-6;
                    app.MaterialEditField.Value = num2str(app.alpha);
               otherwise
                   app.alpha =[];
                  app.MaterialEditField.Value='';

            end


        end

        % Value changed function: MaterialEditField
        function MaterialEditFieldValueChanged(app, event)
           app.alpha  = str2double(app.MaterialEditField.Value);
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
          value=app.EditField_5.Value ;
          app.left_bc= str2double(value);
            
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Callback function
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            app.W=str2double(value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.delta_x=str2double(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.delta_y=str2double(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.delta_t=str2double(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.t_final=str2double(value);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.right_bc=str2double(value);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.top_bc=str2double(value);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.bottom_bc=str2double(value);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.L=str2double(value);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.W=str2double(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ObjectDetailsLabel = uilabel(app.Panel);
            app.ObjectDetailsLabel.Text = 'Object Details';
            app.DiscretizedDomainLabel = uilabel(app.Panel);
            app.DiscretizedDomainLabel.Text = 'Discretized Domain';
            app.InitialConditionLabel = uilabel(app.Panel);
            app.InitialConditionLabel.Text = 'Initial Condition';
            app.BoundaryConditionLabel = uilabel(app.Panel);
            app.BoundaryConditionLabel.Text = 'Boundary Condition';
            app.SpacestepxLabel = uilabel(app.Panel);
            app.SpacestepxLabel.Text = 'Space step, x :';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SpacestepyLabel = uilabel(app.Panel);
            app.SpacestepyLabel.Text = 'Space step, y :';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.TimesteptLabel = uilabel(app.Panel);
            app.TimesteptLabel.Text = 'Time step, t :';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.FinalTimeTLabel = uilabel(app.Panel);
            app.FinalTimeTLabel.Text = 'Final Time, T :';
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.LeftBoundaryConditionLabel = uilabel(app.Panel);
            app.LeftBoundaryConditionLabel.Text = 'Left Boundary Condition :';
            app.EditField_5 = uieditfield(app.Panel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.RightBoundaryConditionLabel = uilabel(app.Panel);
            app.RightBoundaryConditionLabel.Text = 'Right Boundary Condition :';
            app.EditField_6 = uieditfield(app.Panel, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.TopBoundaryConditionLabel = uilabel(app.Panel);
            app.TopBoundaryConditionLabel.Text = 'Top Boundary Condition :';
            app.EditField_7 = uieditfield(app.Panel, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.BottomBoundaryConditionLabel = uilabel(app.Panel);
            app.BottomBoundaryConditionLabel.Text = 'Bottom Boundary Condition :';
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.MaterialEditFieldLabel = uilabel(app.Panel);
            app.MaterialEditFieldLabel.Text = 'Material : ';
            app.MaterialEditField = uieditfield(app.Panel, 'text');
            app.MaterialEditField.ValueChangedFcn = createCallbackFcn(app, @MaterialEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Copper', 'Aluminium', 'Iron', 'Custom '};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Copper';
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Placeholder = 'Ex: sin(x+y)';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'Gaussian', 'sinusoidal', 'Center Block', 'Custom'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Gaussian';
            app.LengthLabel = uilabel(app.Panel);
            app.LengthLabel.Text = 'Length :';
            app.EditField_10 = uieditfield(app.Panel, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.WidthLabel = uilabel(app.Panel);
            app.WidthLabel.Text = 'Width :';
            app.EditField_11 = uieditfield(app.Panel, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.DHeatEquationSolverLabel = uilabel(app.UIFigure);
            app.DHeatEquationSolverLabel.Text = '2D Heat Equation Solver';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpacestepxLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end