classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        PlayButton                    matlab.ui.control.StateButton
        AnimationSlider               matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        RunSimulationButton           matlab.ui.control.Button
        DHeatEquationSolverLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        EditField_11                  matlab.ui.control.EditField
        WidthLabel                    matlab.ui.control.Label
        EditField_10                  matlab.ui.control.EditField
        LengthLabel                   matlab.ui.control.Label
        EditField_9                   matlab.ui.control.EditField
        MaterialEditField             matlab.ui.control.EditField
        MaterialEditFieldLabel        matlab.ui.control.Label
        EditField_8                   matlab.ui.control.EditField
        BottomBoundaryConditionLabel  matlab.ui.control.Label
        EditField_7                   matlab.ui.control.EditField
        TopBoundaryConditionLabel     matlab.ui.control.Label
        EditField_6                   matlab.ui.control.EditField
        RightBoundaryConditionLabel   matlab.ui.control.Label
        EditField_5                   matlab.ui.control.EditField
        LeftBoundaryConditionLabel    matlab.ui.control.Label
        EditField_4                   matlab.ui.control.EditField
        FinalTimeTLabel               matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        TimesteptLabel                matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SpacestepyLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        SpacestepxLabel               matlab.ui.control.Label
        BoundaryConditionLabel        matlab.ui.control.Label
        InitialConditionLabel         matlab.ui.control.Label
        DiscretizedDomainLabel        matlab.ui.control.Label
        ObjectDetailsLabel            matlab.ui.control.Label
        SimulationAxes                matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        
        t_final % Description
        delta_x % Description
        delta_y % Description
        delta_t % Description
        left_bc % Description
        right_bc % Description
        top_bc % Description
        bottom_bc % Description
        initial_condition % Description
        simulation_data % Description
        t_values % Description
        image_handle % Description
        L % Description
        W % Description
        alpha % Description
    end
    
    methods (Access = private)

    function runHeatSimulation(app)
        % 1. Calculate Grids
        col_num = ceil(app.L/app.delta_x) + 1;
        new_dx = app.L/(col_num - 1);
        x_values = 0:new_dx:app.L;
        
        row_num = ceil(app.W/app.delta_y) + 1;
        new_dy = app.W/(row_num - 1);
        y_values = 0:new_dy:app.W;
        
        time_pts = ceil(app.t_final/app.delta_t) + 1;
        new_dt = app.t_final/(time_pts - 1);
        app.t_values = 0:new_dt:app.t_final;

        % 2. Pre-allocate and Stability Check
        app.simulation_data = zeros(row_num, col_num, time_pts);
        
        % 3. Initial Condition Logic
        clean_str = vectorize(lower(replace(char(app.initial_condition), 'e^', 'exp')));
        func_str = ['@(x,y) (', clean_str, ')'];
        [X, Y] = meshgrid(x_values, y_values);
        try
            f_init = str2func(func_str);
            init_mat = f_init(X, Y);
            if isscalar(init_mat), init_mat = init_mat * ones(size(X)); end
            app.simulation_data(:,:,1) = init_mat;
        catch
            uialert(app.UIFigure, 'Invalid Initial Condition syntax.', 'Error');
            return;
        end

        % 4. Apply Boundary Conditions
        app.simulation_data(1,:,:) = app.top_bc;
        app.simulation_data(end,:,:) = app.bottom_bc;
        app.simulation_data(:,end,:) = app.right_bc;
        app.simulation_data(:,1,:) = app.left_bc;

        % 5. Main Solver Loop
        for t = 2:time_pts
            app.simulation_data(2:end-1, 2:end-1, t) = ...
                app.simulation_data(2:end-1, 2:end-1, t-1) + ...
                (app.alpha * new_dt / new_dx^2) * ...
                (app.simulation_data(2:end-1, 3:end, t-1) - 2*app.simulation_data(2:end-1, 2:end-1, t-1) + app.simulation_data(2:end-1, 1:end-2, t-1)) + ...
                (app.alpha * new_dt / new_dy^2) * ...
                (app.simulation_data(3:end, 2:end-1, t-1) - 2*app.simulation_data(2:end-1, 2:end-1, t-1) + app.simulation_data(1:end-2, 2:end-1, t-1));
        end

        % 6. Setup UI for Animation
        app.SimulationAxes.Visible = 'on';
        app.AnimationSlider.Visible = 'on';
        app.PlayButton.Visible = 'on';
        app.ResetButton.Visible = 'on';
        
        % ... After your for-loop math is done ...

        % 1. Set Slider Limits
        app.AnimationSlider.Limits = [1, length(app.t_values)];
        app.AnimationSlider.Value = 1;

        app.PlayButton.Value = 0;
        app.PlayButton.Text = "Play";
        
        % 2. Create the initial plot handle
        app.image_handle = imagesc(app.SimulationAxes, x_values, y_values, app.simulation_data(:,:,1));
        colorbar(app.SimulationAxes);
        axis(app.SimulationAxes, 'equal', 'tight');
        clim(app.SimulationAxes, [min(app.simulation_data(:)), max(app.simulation_data(:))]);
        
        % 3. Make components visible
        app.SimulationAxes.Visible = 'on';
        app.AnimationSlider.Visible = 'on';
        app.PlayButton.Visible = 'on';
        app.ResetButton.Visible = 'on';
        
        app.updateFrame(1); % Show first frame
    end
    function updateFrame(app, val)
        times = app.t_values;
        data = app.simulation_data;
        h = app.image_handle;
        ax = app.SimulationAxes;
        
        idx = round(val);
        if idx < 1, idx = 1; end
        if idx > length(times), idx = length(times); end
        
        % Update the plot and title
        set(h, 'CData', data(:,:,idx));
        title(ax, ['Time: ', num2str(times(idx), '%.3f'), 's']);
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
        % Run the math
        runHeatSimulation(app);
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
           % app.EditField_9.Placeholder='Ex: sin(x+y)';
           app.initial_condition = value;
        end

        % Value changed function: MaterialEditField
        function MaterialEditFieldValueChanged(app, event)
        app.alpha  = str2double(app.MaterialEditField.Value);
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
          value=app.EditField_5.Value ;
          app.left_bc= str2double(value);
            
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.delta_x= str2double(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.delta_y=str2double(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.delta_t=str2double(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.t_final=str2double(value);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.right_bc=str2double(value);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.top_bc=str2double(value);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.bottom_bc=str2double(value);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            app.L=str2double(value);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.W=str2double(value);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            btn = app.PlayButton;
            sld = app.AnimationSlider;
            times = app.t_values;
        
            % 1. Update text based on the button state
            if btn.Value == 1
                btn.Text = 'Pause';
            else
                btn.Text = 'Play';
            end
        
            % 2. Run the loop only while the button is "pressed" (Value == 1)
            while btn.Value == 1 && sld.Value < length(times)
                % Advance the slider
                sld.Value = min(sld.Value + 5, length(times)); 
                
                % Update the visual plot using our helper function
                app.updateFrame(sld.Value);
                
                % In App Designer, we must add drawnow to force the UI to refresh
                drawnow; 
                
                % The critical pause from your working code
                pause(0.01); 
                
                % If we hit the end of the simulation
                if sld.Value >= length(times)
                    btn.Value = 0; % Pop the button back out
                    btn.Text = 'Play';
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.AnimationSlider.Value = 1;
            app.updateFrame(1);
            app.PlayButton.Value = 0;
            app.PlayButton.Text = 'Play';
        end

        % Value changing function: AnimationSlider
        function AnimationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.updateFrame(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.SimulationAxes = uiaxes(app.UIFigure);
            title(app.SimulationAxes, 'Title')
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ObjectDetailsLabel = uilabel(app.Panel);
            app.ObjectDetailsLabel.Text = 'Object Details';
            app.DiscretizedDomainLabel = uilabel(app.Panel);
            app.DiscretizedDomainLabel.Text = 'Discretized Domain';
            app.InitialConditionLabel = uilabel(app.Panel);
            app.InitialConditionLabel.Text = 'Initial Condition';
            app.BoundaryConditionLabel = uilabel(app.Panel);
            app.BoundaryConditionLabel.Text = 'Boundary Condition';
            app.SpacestepxLabel = uilabel(app.Panel);
            app.SpacestepxLabel.Text = 'Space step, x :';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SpacestepyLabel = uilabel(app.Panel);
            app.SpacestepyLabel.Text = 'Space step, y :';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.TimesteptLabel = uilabel(app.Panel);
            app.TimesteptLabel.Text = 'Time step, t :';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.FinalTimeTLabel = uilabel(app.Panel);
            app.FinalTimeTLabel.Text = 'Final Time, T :';
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.LeftBoundaryConditionLabel = uilabel(app.Panel);
            app.LeftBoundaryConditionLabel.Text = 'Left Boundary Condition :';
            app.EditField_5 = uieditfield(app.Panel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.RightBoundaryConditionLabel = uilabel(app.Panel);
            app.RightBoundaryConditionLabel.Text = 'Right Boundary Condition :';
            app.EditField_6 = uieditfield(app.Panel, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.TopBoundaryConditionLabel = uilabel(app.Panel);
            app.TopBoundaryConditionLabel.Text = 'Top Boundary Condition :';
            app.EditField_7 = uieditfield(app.Panel, 'text');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.BottomBoundaryConditionLabel = uilabel(app.Panel);
            app.BottomBoundaryConditionLabel.Text = 'Bottom Boundary Condition :';
            app.EditField_8 = uieditfield(app.Panel, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.MaterialEditFieldLabel = uilabel(app.Panel);
            app.MaterialEditFieldLabel.Text = 'Material : ';
            app.MaterialEditField = uieditfield(app.Panel, 'text');
            app.MaterialEditField.ValueChangedFcn = createCallbackFcn(app, @MaterialEditFieldValueChanged, true);
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Placeholder = 'Ex: sin(x+y)';
            app.LengthLabel = uilabel(app.Panel);
            app.LengthLabel.Text = 'Length :';
            app.EditField_10 = uieditfield(app.Panel, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.WidthLabel = uilabel(app.Panel);
            app.WidthLabel.Text = 'Width :';
            app.EditField_11 = uieditfield(app.Panel, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.DHeatEquationSolverLabel = uilabel(app.UIFigure);
            app.DHeatEquationSolverLabel.Text = '2D Heat Equation Solver';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.AnimationSlider = uislider(app.UIFigure);
            app.AnimationSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationSliderValueChanging, true);
            app.AnimationSlider.Visible = 'off';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SpacestepxLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end